classdef ra_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ParametersEditField       matlab.ui.control.EditField
        ParametersEditFieldLabel  matlab.ui.control.Label
        AS5600Button              matlab.ui.control.Button
        SendParamsButton          matlab.ui.control.Button
        TextArea                  matlab.ui.control.TextArea
        TextAreaLabel             matlab.ui.control.Label
        COMPortDropDown           matlab.ui.control.DropDown
        COMPortDropDownLabel      matlab.ui.control.Label
        ToggleLEDButton           matlab.ui.control.Button
        ConnectSwitch             matlab.ui.control.Switch
    end

    
    properties (Access = private)
        serialObj
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            comPorts = serialportlist("available");  
            
            if isempty(comPorts) % Set dropdown options
                app.COMPortDropDown.Items = {"No COM Ports Found"};
            else
                app.COMPortDropDown.Items = comPorts;
            end
            
            app.AS5600Button.Enable = "off";
            app.ToggleLEDButton.Enable = "off";
        end

        % Value changed function: ConnectSwitch
        function ConnectSwitchValueChanged(app, event)
            COMvalue = app.ConnectSwitch.Value;
            if strcmp(COMvalue, "On")
                selectedPort = string(app.COMPortDropDown.Value); 
                
                if selectedPort == "No COM Ports Found" || selectedPort == ""
                    uialert(app.UIFigure, "No valid COM port selected!", "Error");
                    app.ConnectSwitch.Value = "Off";
                    return;
                end

                if ~isempty(app.serialObj) % 🛑 Close any previous connections
                    delete(app.serialObj);
                    app.serialObj = [];
                    pause(1);  % Wait a bit before reopening
                end

                try % ✅ Try to open port
                    app.serialObj = serialport(selectedPort, 115200, "Timeout", 2);

                    configureTerminator(app.serialObj, "LF");
                    configureCallback(app.serialObj, "terminator", @(src,~) processSerialData(src, app));

                    disp("Successfully opened port: " + selectedPort);
                    
                    app.ConnectSwitch.Value = "On";
                    app.AS5600Button.Enable = "on";
                    app.ToggleLEDButton.Enable = "on";
                    app.COMPortDropDown.Enable = "off";
                catch ME
                    disp("ERROR: " + ME.message);
                    uialert(app.UIFigure, "Failed to open COM port! " + ME.message, "Error");
                    app.ConnectSwitch.Value = "Off"; 
                end
            else              
                app.ConnectSwitch.Value = "Off"; % 🛑 Disconnect
                if ~isempty(app.serialObj)
                    delete(app.serialObj);
                    app.serialObj = [];
                end

                app.AS5600Button.Enable = "off";
                app.ToggleLEDButton.Enable = "off";
                app.COMPortDropDown.Enable = "on";
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            clear app.serialObj;
        end

        % Button pushed function: AS5600Button
        function AS5600ButtonPushed(app, event)
            writeline(app.serialObj, "B");
            app.TextArea.Value = "Waiting for AS5600 data...";
        end

        % Button pushed function: ToggleLEDButton
        function ToggleLEDButtonPushed(app, event)
            writeline(app.serialObj, "A");
        end

        % Button pushed function: SendParamsButton
        function SendParamsButtonPushed(app, event)
            
            message = "F" + app.ParametersEditField.Value;
            writeline(app.serialObj, message);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ConnectSwitch = uiswitch(app.UIFigure, 'slider');
            app.ConnectSwitch.ValueChangedFcn = createCallbackFcn(app, @ConnectSwitchValueChanged, true);
            app.ToggleLEDButton = uibutton(app.UIFigure, 'push');
            app.ToggleLEDButton.ButtonPushedFcn = createCallbackFcn(app, @ToggleLEDButtonPushed, true);
            app.ToggleLEDButton.Text = 'Toggle LED';
            app.COMPortDropDownLabel = uilabel(app.UIFigure);
            app.COMPortDropDownLabel.Text = 'COM Port';
            app.COMPortDropDown = uidropdown(app.UIFigure);
            app.COMPortDropDown.Items = {};
            app.COMPortDropDown.Value = {};
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
            app.SendParamsButton = uibutton(app.UIFigure, 'push');
            app.SendParamsButton.ButtonPushedFcn = createCallbackFcn(app, @SendParamsButtonPushed, true);
            app.SendParamsButton.Text = 'SendParams';
            app.AS5600Button = uibutton(app.UIFigure, 'push');
            app.AS5600Button.ButtonPushedFcn = createCallbackFcn(app, @AS5600ButtonPushed, true);
            app.AS5600Button.Text = 'AS5600';
            app.ParametersEditFieldLabel = uilabel(app.UIFigure);
            app.ParametersEditFieldLabel.Text = 'Parameters';
            app.ParametersEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ra_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end